classdef EducatorScene < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        QuizNameEditField           matlab.ui.control.EditField
        QuizNameEditFieldLabel      matlab.ui.control.Label
        AddQuestionButton           matlab.ui.control.Button
        QuestionCountLabel          matlab.ui.control.Label
        DoneButton                  matlab.ui.control.Button
        CorrectAnswerDropDown       matlab.ui.control.DropDown
        CorrectAnswerDropDownLabel  matlab.ui.control.Label
        Option4EditField            matlab.ui.control.EditField
        Enteroption4EditFieldLabel  matlab.ui.control.Label
        Option3EditField            matlab.ui.control.EditField
        Enteroption3EditFieldLabel  matlab.ui.control.Label
        Option2EditField            matlab.ui.control.EditField
        Enteroption2EditFieldLabel  matlab.ui.control.Label
        Option1EditField            matlab.ui.control.EditField
        EnterOption1EditFieldLabel  matlab.ui.control.Label
        EnterQuestionHereEditField  matlab.ui.control.EditField
        EnterQuestionHereEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        QuestionList = {}; % Cell array to store questions
        ChoiceList = {}; % Cell array to store choices
        CorrectAnswerList = {}; % Cell array to store correct answers
        CurrentQuestionIndex = 1; % Index to keep track of the current question % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: AddQuestionButton
        function AddQuestionButtonPushed(app, event)
 % Add current question and choices to the list
            app.QuestionList{app.CurrentQuestionIndex} = app.EnterQuestionHereEditField.Value;
            app.ChoiceList{app.CurrentQuestionIndex, 1} = app.Option1EditField.Value;
            app.ChoiceList{app.CurrentQuestionIndex, 2} = app.Option2EditField.Value;
            app.ChoiceList{app.CurrentQuestionIndex, 3} = app.Option3EditField.Value;
            app.ChoiceList{app.CurrentQuestionIndex, 4} = app.Option4EditField.Value;
            
            % Identify correct answer
            if app.CorrectAnswerDropDown.Value == "Option 1"
                app.CorrectAnswerList{app.CurrentQuestionIndex} = app.Option1EditField.Value;
            elseif app.CorrectAnswerDropDown.Value == "Option 2"
                app.CorrectAnswerList{app.CurrentQuestionIndex} = app.Option2EditField.Value;
            elseif app.CorrectAnswerDropDown.Value == "Option 3"
                app.CorrectAnswerList{app.CurrentQuestionIndex} = app.Option3EditField.Value;
            else
                app.CorrectAnswerList{app.CurrentQuestionIndex} = app.Option4EditField.Value;
            end

            % Increment question index
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;

            % Update question counter label
            app.QuestionCountLabel.Text = sprintf('Question # %d', app.CurrentQuestionIndex);

            % Clear fields for new question
            app.EnterQuestionHereEditField.Value = '';
            app.Option1EditField.Value = '';
            app.Option2EditField.Value = '';
            app.Option3EditField.Value = '';
            app.Option4EditField.Value = '';
            app.CorrectAnswerDropDown.Value = 'Option 1';

        
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
                                    % Get the name for the question set
            questionSetName = app.QuizNameEditField.Value;
            if isempty(questionSetName)
                uialert(app.UIFigure, 'Please enter a name for the question set.', 'Error');
                return;
            end

            % Construct the filename
            filename = sprintf('%s.xlsx', questionSetName);

            % Prepare data for writing to Excel
            numQuestions = length(app.QuestionList);
            data = cell(numQuestions, 6); % 1 question + 4 choices + 1 correct answer
            for i = 1:numQuestions
                data{i, 1} = app.QuestionList{i};
                data{i, 2} = app.ChoiceList{i, 1};
                data{i, 3} = app.ChoiceList{i, 2};
                data{i, 4} = app.ChoiceList{i, 3};
                data{i, 5} = app.ChoiceList{i,4};
                data{i, 6} = app.CorrectAnswerList{i};
            end

            % Save data to an Excel file
            writecell(data, filename);

            mainApp();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterQuestionHereEditFieldLabel = uilabel(app.UIFigure);
            app.EnterQuestionHereEditFieldLabel.Text = 'Enter Question Here';
            app.EnterQuestionHereEditField = uieditfield(app.UIFigure, 'text');
            app.EnterOption1EditFieldLabel = uilabel(app.UIFigure);
            app.EnterOption1EditFieldLabel.Text = 'Enter Option 1';
            app.Option1EditField = uieditfield(app.UIFigure, 'text');
            app.Enteroption2EditFieldLabel = uilabel(app.UIFigure);
            app.Enteroption2EditFieldLabel.Text = 'Enter option 2';
            app.Option2EditField = uieditfield(app.UIFigure, 'text');
            app.Enteroption3EditFieldLabel = uilabel(app.UIFigure);
            app.Enteroption3EditFieldLabel.Text = 'Enter option 3';
            app.Option3EditField = uieditfield(app.UIFigure, 'text');
            app.Enteroption4EditFieldLabel = uilabel(app.UIFigure);
            app.Enteroption4EditFieldLabel.Text = 'Enter option 4';
            app.Option4EditField = uieditfield(app.UIFigure, 'text');
            app.CorrectAnswerDropDownLabel = uilabel(app.UIFigure);
            app.CorrectAnswerDropDownLabel.Text = 'CorrectAnswer';
            app.CorrectAnswerDropDown = uidropdown(app.UIFigure);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.QuestionCountLabel = uilabel(app.UIFigure);
            app.QuestionCountLabel.Text = 'Question # 1';
            app.AddQuestionButton = uibutton(app.UIFigure, 'push');
            app.AddQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @AddQuestionButtonPushed, true);
            app.AddQuestionButton.Text = 'Add Question';
            app.QuizNameEditFieldLabel = uilabel(app.UIFigure);
            app.QuizNameEditFieldLabel.Text = 'Quiz Name';
            app.QuizNameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EducatorScene

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end