classdef StudentScene < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        WrongAnswers0Label    matlab.ui.control.Label
        CorrectAnswers0Label  matlab.ui.control.Label
        Option4Button         matlab.ui.control.Button
        Option3Button         matlab.ui.control.Button
        Option2Button         matlab.ui.control.Button
        Option1Button         matlab.ui.control.Button
        QuestionLabel         matlab.ui.control.Label
    end

  properties (Access = private)
        QuestionSet                cell
        CurrentQuestionIndex       double = 1
        TotalQuestions             double
        CorrectAnswers             double = 0
        Answers                    cell
        StatsFilename              char
        WrongAnswers               double = 0
    end
    
    methods (Access = private)

        function shuffleQuestions(app)
        % Shuffle the rows of QuestionSet
        numQuestions = size(app.QuestionSet, 1);
        shuffledIndices = randperm(numQuestions);
        app.QuestionSet = app.QuestionSet(shuffledIndices, :);
        end


    function displayNextQuestion(app)
        if app.CurrentQuestionIndex > app.TotalQuestions
            app.endQuiz();
            return;
        end

        app.QuestionLabel.Text = app.QuestionSet{app.CurrentQuestionIndex, 1};
        app.Option1Button.Text = app.QuestionSet{app.CurrentQuestionIndex, 2};
        app.Option2Button.Text = app.QuestionSet{app.CurrentQuestionIndex, 3};
        app.Option3Button.Text = app.QuestionSet{app.CurrentQuestionIndex, 4};
        app.Option4Button.Text = app.QuestionSet{app.CurrentQuestionIndex, 5};
  
    end

        function recordAnswer(app, selectedChoice)
            correctAnswer = app.QuestionSet{app.CurrentQuestionIndex, 6};
            if strcmp(selectedChoice, correctAnswer)
                app.CorrectAnswers = app.CorrectAnswers + 1;
                app.Answers{app.CurrentQuestionIndex} = 'Correct';
                app.CorrectAnswers0Label.Text = sprintf('Correct:%d', app.CorrectAnswers);
            else
                app.Answers{app.CurrentQuestionIndex} = 'Wrong';
                app.WrongAnswers = app.WrongAnswers + 1;
                app.WrongAnswers0Label.Text = sprintf('Wrong:%d', app.WrongAnswers);
            end
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
        end

        function endQuiz(app)
            % Read the existing data from the sheet
            [~, ~, raw] = xlsread(app.StatsFilename);
            numAttempts = size(raw, 2) - 6;
            
            % Increment
            attemptNumber = numAttempts + 1;
            
            % Save stats to the same Excel file in a new sheet
            stats = {'Question', 'Your Answer', 'Correct Answer'};
            for i = 1:app.TotalQuestions
                stats{end+1, 1} = app.QuestionSet{i, 1};
                stats{end, 2} = app.Answers{i};
                stats{end, 3} = app.QuestionSet{i, 6};
            end
            stats{end+1, 1} = 'Total Correct';
            stats{end, 2} = app.CorrectAnswers;
            
            
            sheetName = 'Stats';
            
            % Write the stats to the Excel file
            writecell(stats, app.StatsFilename, 'Sheet', sheetName);
            
            % Prepare data to write to the main sheet
            attemptColumn = 6 + attemptNumber;
            data = cell(app.TotalQuestions + 1, 1);
            data{1} = sprintf('Attempt %d', attemptNumber);
            for i = 1:app.TotalQuestions
                data{i+1} = app.Answers{i};
            end
            data{end+1} = sprintf('Score: %d/%d', app.CorrectAnswers, app.TotalQuestions);
            
            % Writing to the mainsheet
            writecell(data, app.StatsFilename, 'Sheet', 1, 'Range', sprintf('%c1', char('F' + attemptNumber)));
            
            % Message for when the exam is done
            alertMessage = sprintf('You are done with the exam! You scored %d/%d', app.CorrectAnswers, app.TotalQuestions);
            
            
            selection = uiconfirm(app.UIFigure, alertMessage, 'End of Quiz', ...
                'Options', {'OK'}, ...
                'DefaultOption', 1, 'CloseFcn', @(~, event)closeApp(app, event));
        end

        % Callback function to close the app after the alert is acknowledged
        function closeApp(app, event)
            if strcmp(event.SelectedOption, 'OK')
                mainApp();
            else
                mainApp()
            end
            delete(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             [file, path] = uigetfile('*.xlsx', 'Select Question Set');
            if isequal(file, 0)
                return;
            end
            questionSetFile = fullfile(path, file);
            % Read the entire Excel file
            [~, ~, raw] = xlsread(questionSetFile);
            
            % Determine the rows that contain questions by checking if the first column is a number or empty
            questionRows = ~cellfun(@isempty, raw(:, 1)) & ~cellfun(@(x) isnumeric(x) && isnan(x), raw(:, 1));
            
           
            raw = raw(questionRows, 1:6);
            
            % Extract the question set
            app.QuestionSet = raw;
            app.TotalQuestions = size(app.QuestionSet, 1);
            app.StatsFilename = questionSetFile;
            app.shuffleQuestions(); % Shuffle questions before starting
            app.CurrentQuestionIndex = 1;
            app.displayNextQuestion(); % Display the first question
          
        end

        % Button pushed function: Option1Button
        function Option1ButtonPushed(app, event)
            app.recordAnswer(app.Option1Button.Text);
            app.displayNextQuestion();
        end

        % Button pushed function: Option2Button
        function Option2ButtonPushed(app, event)
           app.recordAnswer(app.Option2Button.Text);
           app.displayNextQuestion();
        end

        % Button pushed function: Option3Button
        function Option3ButtonPushed(app, event)
           app.recordAnswer(app.Option3Button.Text);
           app.displayNextQuestion();
        end

        % Button pushed function: Option4Button
        function Option4ButtonPushed(app, event)
           app.recordAnswer(app.Option4Button.Text);
           app.displayNextQuestion();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.QuestionLabel = uilabel(app.UIFigure);
            app.QuestionLabel.Text = 'QuestionLabel';
            app.Option1Button = uibutton(app.UIFigure, 'push');
            app.Option1Button.ButtonPushedFcn = createCallbackFcn(app, @Option1ButtonPushed, true);
            app.Option1Button.Text = 'Option1';
            app.Option2Button = uibutton(app.UIFigure, 'push');
            app.Option2Button.ButtonPushedFcn = createCallbackFcn(app, @Option2ButtonPushed, true);
            app.Option2Button.Text = 'Option2';
            app.Option3Button = uibutton(app.UIFigure, 'push');
            app.Option3Button.ButtonPushedFcn = createCallbackFcn(app, @Option3ButtonPushed, true);
            app.Option3Button.Text = 'Option3';
            app.Option4Button = uibutton(app.UIFigure, 'push');
            app.Option4Button.ButtonPushedFcn = createCallbackFcn(app, @Option4ButtonPushed, true);
            app.Option4Button.Text = 'Option4';
            app.CorrectAnswers0Label = uilabel(app.UIFigure);
            app.CorrectAnswers0Label.Text = 'Correct Answers: 0';
            app.WrongAnswers0Label = uilabel(app.UIFigure);
            app.WrongAnswers0Label.Text = 'Wrong Answers: 0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StudentScene

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end